classdef main_cn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NPMFOPDTUNERUIFigure  matlab.ui.Figure
        NPMFOPDLabel          matlab.ui.control.Label
        NeighborhoodphasemarginNPMLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Button                matlab.ui.control.Button
        IRIDButton            matlab.ui.control.Button
        Panel                 matlab.ui.container.Panel
        Button_2              matlab.ui.control.Button
        KEditFieldLabel       matlab.ui.control.Label
        KEditField            matlab.ui.control.NumericEditField
        FOPDPanel             matlab.ui.container.Panel
        k_pLabel_2            matlab.ui.control.Label
        k_pEditField_fo       matlab.ui.control.NumericEditField
        k_dLabel_2            matlab.ui.control.Label
        k_dEditField_fo       matlab.ui.control.NumericEditField
        lambdaEditFieldLabel  matlab.ui.control.Label
        lambdaEditField       matlab.ui.control.NumericEditField
        FOPDButton            matlab.ui.control.Button
        IOPIDPanel            matlab.ui.container.Panel
        IOPIDButton           matlab.ui.control.Button
        IOPDButton            matlab.ui.control.Button
        k_pLabel              matlab.ui.control.Label
        k_pEditField          matlab.ui.control.NumericEditField
        k_iLabel              matlab.ui.control.Label
        k_iEditField          matlab.ui.control.NumericEditField
        k_dLabel              matlab.ui.control.Label
        k_dEditField          matlab.ui.control.NumericEditField
        PlantparametersPanel  matlab.ui.container.Panel
        kEditFieldLabel       matlab.ui.control.Label
        kEditField            matlab.ui.control.NumericEditField
        tauEditFieldLabel     matlab.ui.control.Label
        tauEditField          matlab.ui.control.NumericEditField
        w_cEditFieldLabel     matlab.ui.control.Label
        w_cEditField          matlab.ui.control.NumericEditField
        phiEditFieldLabel     matlab.ui.control.Label
        phiEditField          matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IOPIDButton
        function IOPIDButtonPushed(app, event)
            app.Label_2.Text='Running.';
            global K T1 wc phi %inputs
            K = app.kEditField.Value; %plant gain
            T1 = app.tauEditField.Value; %plant time constant
            wc = app.w_cEditField.Value; %crossover frequency
            phi = app.phiEditField.Value; %phase margin
            pause on % to enable pause function
            pause(0.1);
            iopid_tune;
            %run_patternsearch_npm;
            %app.k_pEditField.Value=800;
            global iokp ioki iokd
            
            app.k_pEditField.Value=iokp;
            app.k_iEditField.Value=ioki;
            app.k_dEditField.Value=iokd;
            app.Label_2.Text='Ready.';   
        end

        % Button pushed function: FOPDButton
        function FOPDButtonPushed(app, event)
            app.Label_2.Text='Running.';
            
            pause on
            pause(0.1);
            global K T1
            K = app.kEditField.Value; %plant gain
            T1 = app.tauEditField.Value; %plant time constant
            global omega_c phi_m
            omega_c=app.w_cEditField.Value;
            phi_m=app.phiEditField.Value;
            global ioKp ioKi ioKd
            ioKp=app.k_pEditField.Value;
            ioKi=app.k_iEditField.Value;
            ioKd=app.k_dEditField.Value;
            run_patternsearch_apm;
            global lamda kd kp
            app.k_pEditField_fo.Value=kp;
%             app.k_iEditField_fo.Value=x(2);
            app.k_dEditField_fo.Value=kd;
            app.lambdaEditField.Value=lamda;
%             app.muEditField.Value=x(5);
            app.Label_2.Text='Ready.';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Label_2.Text='Running.';
            
            pause on
            pause(0.1);
            run_simulink_kd;
            app.Label_2.Text='Ready.';
        end

        % Callback function
        function LoadSimscapeplantButtonPushed(app, event)
            app.kEditField.Value = 1.102; %plant gain (k)
            app.tauEditField.Value = 0.179; %(T1) plant time constant

        end

        % Button pushed function: IOPDButton
        function IOPDButtonPushed(app, event)
            app.Label_2.Text='Running.';
            global K T1 wc phi %inputs
            K = app.kEditField.Value; %plant gain
            T1 = app.tauEditField.Value; %plant time constant
            wc = app.w_cEditField.Value; %crossover frequency
            phi = app.phiEditField.Value; %phase margin
            pause on % to enable pause function
            pause(0.1);
            iopd_tune;
            %run_patternsearch_npm;
            %app.k_pEditField.Value=800;
            global iokp ioki iokd
            
            app.k_pEditField.Value=iokp;
            app.k_iEditField.Value=ioki;
            app.k_dEditField.Value=iokd;
            app.Label_2.Text='Ready.';    
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
%             figure(3);
%             hold on
            app.Label_2.Text='Running.';
            factor=app.KEditField.Value;
            pause on
            pause(0.1);
            figure(3);hold off
%             figure(4);hold off
            run_simulink_kd;
            hold on
            global K
            K0=K;
            K=K0/factor;
            run_simulink_kd;
            K=K0*factor;
            run_simulink_kd;
            K=K0;
            hold off
            figure(3);
            xlabel('Time (second)')
            ylabel('Altitude (meter)')
            legend(['FOPD: K=' num2str(K)],...
                ['IOPID: K=' num2str(K)],...
                ['FOPD: K=' num2str(K) '/' num2str(factor)],...
                ['IOPID: K=' num2str(K) '/' num2str(factor)],...
                ['FOPD: K=' num2str(K) '*' num2str(factor)],...
                ['IOPID: K=' num2str(K) '*' num2str(factor)]...
                )
%             figure(4);
%             legend(['FOPD: K=' num2str(K)],...
%                 ['IOPID: K=' num2str(K) ', I=' num2str(ioki)],...
%                 ['FOPD: K=' num2str(K) '*0.5'],...
%                 ['IOPID: K=' num2str(K) '*0.5' ', I=' num2str(ioki)],...
%                 ['FOPD: K=' num2str(K) '*1.5'],...
%                 ['IOPID: K=' num2str(K) '*1.5' ', I=' num2str(ioki)]...
%                 )

            app.Label_2.Text='Ready.';
        end

        % Button pushed function: IRIDButton
        function IRIDButtonPushed(app, event)
            gam=app.lambdaEditField.Value;
            Ts=0.1;
            dfod=irid_fod(gam,Ts,5);
            assignin('base','dfod',dfod);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NPMFOPDTUNERUIFigure = uifigure('Visible', 'off');
            app.NPMFOPDTUNERUIFigure.Name = 'NPM FOPD TUNER';
            app.NPMFOPDLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.NPMFOPDLabel.Text = 'NPM FOPD 控制器参数整定器';
            app.NeighborhoodphasemarginNPMLabel = uilabel(app.NPMFOPDTUNERUIFigure);
            app.NeighborhoodphasemarginNPMLabel.Text = '领域相位裕度整定器（Neighborhood phase margin，NPM） ';
            app.Label = uilabel(app.NPMFOPDTUNERUIFigure);
            app.Label.Text = '状态：';
            app.Label_2 = uilabel(app.NPMFOPDTUNERUIFigure);
            app.Label_2.Text = '就绪。';
            app.Button = uibutton(app.NPMFOPDTUNERUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '线性模型阶跃响应';
            app.IRIDButton = uibutton(app.NPMFOPDTUNERUIFigure, 'push');
            app.IRIDButton.ButtonPushedFcn = createCallbackFcn(app, @IRIDButtonPushed, true);
            app.IRIDButton.Text = '单位冲击响应离散化IRID';
            app.Panel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.Panel.Title = '鲁棒性';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '鲁棒性分析';
            app.KEditFieldLabel = uilabel(app.Panel);
            app.KEditFieldLabel.Text = 'K 参数:';
            app.KEditField = uieditfield(app.Panel, 'numeric');
            app.KEditField.Value = 2;
            app.FOPDPanel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.FOPDPanel.Title = 'FOPD控制器参数';
            app.k_pLabel_2 = uilabel(app.FOPDPanel);
            app.k_pLabel_2.Text = '比例增益（k_p）';
            app.k_pEditField_fo = uieditfield(app.FOPDPanel, 'numeric');
            app.k_dLabel_2 = uilabel(app.FOPDPanel);
            app.k_dLabel_2.Text = '微分增益（k_d）';
            app.k_dEditField_fo = uieditfield(app.FOPDPanel, 'numeric');
            app.lambdaEditFieldLabel = uilabel(app.FOPDPanel);
            app.lambdaEditFieldLabel.Text = '分数阶指数(lambda)';
            app.lambdaEditField = uieditfield(app.FOPDPanel, 'numeric');
            app.FOPDButton = uibutton(app.FOPDPanel, 'push');
            app.FOPDButton.ButtonPushedFcn = createCallbackFcn(app, @FOPDButtonPushed, true);
            app.FOPDButton.Text = 'FOPD控制器参数整定';
            app.IOPIDPanel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.IOPIDPanel.Title = 'IOPID控制器参数';
            app.IOPIDButton = uibutton(app.IOPIDPanel, 'push');
            app.IOPIDButton.ButtonPushedFcn = createCallbackFcn(app, @IOPIDButtonPushed, true);
            app.IOPIDButton.Text = 'IOPID控制器参数整定';
            app.IOPDButton = uibutton(app.IOPIDPanel, 'push');
            app.IOPDButton.ButtonPushedFcn = createCallbackFcn(app, @IOPDButtonPushed, true);
            app.IOPDButton.Text = 'IOPD控制器参数整定';
            app.k_pLabel = uilabel(app.IOPIDPanel);
            app.k_pLabel.Text = '比例增益（k_p）';
            app.k_pEditField = uieditfield(app.IOPIDPanel, 'numeric');
            app.k_iLabel = uilabel(app.IOPIDPanel);
            app.k_iLabel.Text = '积分增益（k_i）';
            app.k_iEditField = uieditfield(app.IOPIDPanel, 'numeric');
            app.k_dLabel = uilabel(app.IOPIDPanel);
            app.k_dLabel.Text = '微分增益（k_d）';
            app.k_dEditField = uieditfield(app.IOPIDPanel, 'numeric');
            app.PlantparametersPanel = uipanel(app.NPMFOPDTUNERUIFigure);
            app.PlantparametersPanel.Title = '被控对象参数（Plant parameters）';
            app.kEditFieldLabel = uilabel(app.PlantparametersPanel);
            app.kEditFieldLabel.Text = '被控对象增益 (k)';
            app.kEditField = uieditfield(app.PlantparametersPanel, 'numeric');
            app.kEditField.ValueDisplayFormat = '%11.5g';
            app.kEditField.Value = 1.005;
            app.tauEditFieldLabel = uilabel(app.PlantparametersPanel);
            app.tauEditFieldLabel.Text = '被控对象时间常数 (tau)';
            app.tauEditField = uieditfield(app.PlantparametersPanel, 'numeric');
            app.tauEditField.Value = 1.078;
            app.w_cEditFieldLabel = uilabel(app.PlantparametersPanel);
            app.w_cEditFieldLabel.Text = '系统穿越频率 (w_c)';
            app.w_cEditField = uieditfield(app.PlantparametersPanel, 'numeric');
            app.w_cEditField.Value = 2.51;
            app.phiEditFieldLabel = uilabel(app.PlantparametersPanel);
            app.phiEditFieldLabel.Text = '系统相位裕度 (phi)';
            app.phiEditField = uieditfield(app.PlantparametersPanel, 'numeric');
            app.phiEditField.Value = 83.9;
            % Show the figure after all components are created
            app.NPMFOPDTUNERUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_cn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NPMFOPDTUNERUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NPMFOPDTUNERUIFigure)
        end
    end
end